classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        PCCBreakerSwitch              matlab.ui.control.RockerSwitch
        PCCBreakerSwitchLabel         matlab.ui.control.Label
        QGridEditField                matlab.ui.control.EditField
        QGridEditFieldLabel           matlab.ui.control.Label
        PGridEditField                matlab.ui.control.EditField
        PGridEditFieldLabel           matlab.ui.control.Label
        phiMicroGridEditField         matlab.ui.control.EditField
        phiMicroGridEditFieldLabel    matlab.ui.control.Label
        phiUtilityGridEditField       matlab.ui.control.EditField
        phiUtilityGridEditFieldLabel  matlab.ui.control.Label
        iMicrogridEditField_2         matlab.ui.control.EditField
        iMicrogridEditField_2Label    matlab.ui.control.Label
        iUtilityGridEditField         matlab.ui.control.EditField
        iUtilityGridEditFieldLabel    matlab.ui.control.Label
        vMicroGridEditField           matlab.ui.control.EditField
        vMicroGridEditFieldLabel      matlab.ui.control.Label
        vUtilityGridEditField         matlab.ui.control.EditField
        vUtilityGridEditFieldLabel    matlab.ui.control.Label
        DisconnectFromIEDsButton      matlab.ui.control.Button
        ConnectToIEDsButton           matlab.ui.control.Button
    end

 properties (Access = private)
        pyiec
        con
        isConnected logical = false

        cfg struct = struct( ...
            "host","192.168.1.201", ...
            "port",102, ...
            "LD","PCC1PCC1LD0", ...
            "GGIO","GGIO1" ...
        );

        pollTimer = []          % keep empty until created
        pollPeriod double = 1.0
        inSwitchCallback logical = false

        uiMap struct
    end

    % ==================== PRIVATE HELPERS ====================
    methods (Access = private)

        function startupFcn(app)
            app.uiMap = app.buildUiMap();

            [ok, msg] = app.importIecModule();
            if ~ok
                uialert(app.UIFigure, msg, "IEC61850");
                return;
            end

            app.ensureTimer();

            app.DisconnectFromIEDsButton.Enable = "off";
            app.ConnectToIEDsButton.Enable = "on";

            app.inSwitchCallback = true;
            app.PCCBreakerSwitch.Value = "Off";
            app.inSwitchCallback = false;
        end

        function ensureTimer(app)
            try
                if isempty(app.pollTimer)
                    % create below
                elseif ~isa(app.pollTimer, 'timer')
                    try delete(app.pollTimer); catch, end %#ok<TRYNC>
                    app.pollTimer = [];
                elseif ~isvalid(app.pollTimer)
                    app.pollTimer = [];
                end
            catch
                app.pollTimer = [];
            end

            if isempty(app.pollTimer)
                app.pollTimer = timer( ...
                    "ExecutionMode","fixedSpacing", ...
                    "Period", app.pollPeriod, ...
                    "BusyMode","drop", ...
                    "TimerFcn", @(~,~)app.pollOnce() );
            else
                try
                    app.pollTimer.Period = app.pollPeriod;
                catch
                end
            end
        end

        function m = buildUiMap(app)
            m = struct();
            m.AnIn = containers.Map('KeyType','double','ValueType','any');

            m.AnIn(1) = app.vUtilityGridEditField;
            m.AnIn(2) = app.vMicroGridEditField;
            m.AnIn(3) = app.iUtilityGridEditField;
            m.AnIn(4) = app.iMicrogridEditField_2;
            m.AnIn(5) = app.phiUtilityGridEditField;
            m.AnIn(6) = app.phiMicroGridEditField;
            m.AnIn(7) = app.PGridEditField;
            m.AnIn(8) = app.QGridEditField;
        end

        function [ok, msg] = importIecModule(app)
            ok = false; msg = "";
            try
                app.pyiec = py.importlib.import_module("pyiec61850");
                ok = true; return;
            catch ME1
                try
                    app.pyiec = py.importlib.import_module("iec61850");
                    ok = true; return;
                catch ME2
                    app.pyiec = [];
                    msg = "Python import failed. Fix MATLAB Python env." + newline + ...
                          "Run in MATLAB:" + newline + ...
                          "  pyenv" + newline + ...
                          "  pyenv('Version','/path/to/python3')" + newline + ...
                          "Then:" + newline + ...
                          "  py.importlib.import_module('pyiec61850')" + newline + ...
                          "Error1: " + string(ME1.message) + newline + ...
                          "Error2: " + string(ME2.message);
                end
            end
        end

        function ref = aninRef(app, idx)
            ref = sprintf('%s/%s.AnIn%d.mag.f', app.cfg.LD, app.cfg.GGIO, idx);
        end

        function ref = stValRef(app)
            ref = sprintf('%s/%s.SPCSO1.stVal', app.cfg.LD, app.cfg.GGIO);
        end

        function ref = ctrlObjRef(app)
            ref = sprintf('%s/%s.SPCSO1', app.cfg.LD, app.cfg.GGIO);
        end

        function [ok, msg] = connectIed(app)
            ok = false; msg = "";

            if isempty(app.pyiec)
                [ok, msg] = app.importIecModule();
                if ~ok, return; end
            end

            try
                app.con = app.pyiec.IedConnection_create();
                app.pyiec.IedConnection_connect(app.con, app.cfg.host, int32(app.cfg.port));
                app.isConnected = true;
                ok = true;
            catch ME
                app.con = [];
                app.isConnected = false;
                msg = "Connect failed: " + string(ME.message);
            end
        end

        function disconnectIed(app)
            try
                if isa(app.pollTimer,'timer') && isvalid(app.pollTimer)
                    stop(app.pollTimer);
                end
            catch
            end

            try if ~isempty(app.con), app.pyiec.IedConnection_close(app.con); end; catch, end
            try if ~isempty(app.con), app.pyiec.IedConnection_destroy(app.con); end; catch, end

            app.con = [];
            app.isConnected = false;
        end

        function pollOnce(app)
            if ~app.isConnected || isempty(app.con)
                return;
            end

            if isempty(app.uiMap) || ~isfield(app.uiMap,'AnIn') || isempty(app.uiMap.AnIn)
                app.uiMap = app.buildUiMap();
            end

            try
                FC_MX = app.pyiec.IEC61850_FC_MX;
                FC_ST = app.pyiec.IEC61850_FC_ST;

                % breaker status (SAFE scalar conversion)
                st_py = app.pyiec.IedConnection_readBooleanValue(app.con, app.stValRef(), FC_ST);
                breakerOn = app.pyToBool(st_py);

                % analog values (Map lookup correctly)
                for idx = 1:8
                    v_py = app.pyiec.IedConnection_readFloatValue(app.con, app.aninRef(idx), FC_MX);
                    v = app.pyToDouble(v_py);

                    fld = app.uiMap.AnIn(idx);
                    fld.Value = num2str(v);
                end

                app.inSwitchCallback = true;
                app.PCCBreakerSwitch.Value = app.onOff(breakerOn);
                app.inSwitchCallback = false;

            catch ME
                app.inSwitchCallback = false;
                uialert(app.UIFigure, "pollOnce failed:" + newline + string(ME.message), "IEC61850");
            end
        end

        function [ok, msg] = operateBreaker(app, desiredOn)
            ok = false; msg = "";

            if ~app.isConnected || isempty(app.con)
                msg = "Not connected"; return;
            end

            control = [];
            ctlVal = [];
            try
                control = app.pyiec.ControlObjectClient_create(app.ctrlObjRef(), app.con);
                if isempty(control)
                    msg = "ControlObjectClient_create returned NULL"; return;
                end

                ctlVal = app.pyiec.MmsValue_newBoolean(logical(desiredOn));
                ok_py = app.pyiec.ControlObjectClient_operate(control, ctlVal, int32(0));
                ok = logical(ok_py);

                if ~ok
                    try
                        last = app.pyiec.ControlObjectClient_getLastError(control);
                        msg = "Operate failed. lastError=" + string(last);
                    catch
                        msg = "Operate failed.";
                    end
                end
            catch ME
                msg = "Operate exception: " + string(ME.message);
            end

            try if ~isempty(ctlVal), app.pyiec.MmsValue_delete(ctlVal); end; catch, end
            try if ~isempty(control), app.pyiec.ControlObjectClient_destroy(control); end; catch, end
        end

        function s = onOff(~, tf)
            if tf, s = "On"; else, s = "Off"; end
        end

        function b = pyToBool(app, x) %#ok<INUSD>
            % Always return a scalar logical, regardless of Python/SWIG return type

            % 1) already MATLAB logical/numeric
            try
                if islogical(x)
                    b = logical(x(1));
                    return;
                end
            catch
            end
            try
                if isnumeric(x)
                    b = logical(x(1));
                    return;
                end
            catch
            end

            % 2) if SWIG returned MmsValue and helper exists
            try
                if ~isempty(app.pyiec) && ismethod(app.pyiec, "MmsValue_toBoolean")
                    b = logical(app.pyiec.MmsValue_toBoolean(x));
                    return;
                end
            catch
            end

            % 3) string fallback (may be scalar OR array) -> reduce with ANY
            s = lower(string(x));
            b = any(s == "true") || any(s == "1");
        end


        function v = pyToDouble(app, x)
            % Robust conversion of Python numeric-like values to MATLAB double

            % 1) numeric/logical already in MATLAB
            try
                if isnumeric(x) || islogical(x)
                    v = double(x(1));
                    return;
                end
            catch
            end

            % 2) many Python numeric objects convert with double()
            try
                v = double(x);
                return;
            catch
            end

            % 3) if SWIG returns MmsValue, try MmsValue_toFloat/Double if exposed
            try
                if ~isempty(app.pyiec)
                    if ismethod(app.pyiec, "MmsValue_toFloat")
                        v = double(app.pyiec.MmsValue_toFloat(x));
                        return;
                    elseif ismethod(app.pyiec, "MmsValue_toDouble")
                        v = double(app.pyiec.MmsValue_toDouble(x));
                        return;
                    end
                end
            catch
            end

            % 4) string parse (extract first numeric token)
            s = string(x);
            tok = regexp(s, '[-+]?\d*\.?\d+([eE][-+]?\d+)?', 'match', 'once');
            if isempty(tok)
                v = NaN;
            else
                v = str2double(tok);
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConnectToIEDsButton
        function ConnectToIEDsButtonPushed(app, event)
           app.ensureTimer();

            [ok, msg] = app.connectIed();
            if ~ok
                uialert(app.UIFigure, msg, "IEC61850");
                return;
            end

            try
                if isa(app.pollTimer,'timer') && isvalid(app.pollTimer)
                    start(app.pollTimer);
                else
                    uialert(app.UIFigure, "pollTimer is not a timer object. Check startupFcn binding.", "IEC61850");
                end
            catch ME
                uialert(app.UIFigure, "Timer start failed: " + string(ME.message), "IEC61850");
            end

            app.ConnectToIEDsButton.Enable = "off";
            app.DisconnectFromIEDsButton.Enable = "on";

            app.pollOnce();
        end

        % Button pushed function: DisconnectFromIEDsButton
        function DisconnectFromIEDsButtonPushed(app, event)
            app.disconnectIed();

            app.ConnectToIEDsButton.Enable = "on";
            app.DisconnectFromIEDsButton.Enable = "off";

            % optional clear UI (Map needs lookup first)
            try
                if isempty(app.uiMap) || ~isfield(app.uiMap,'AnIn') || isempty(app.uiMap.AnIn)
                    app.uiMap = app.buildUiMap();
                end
                for idx=1:8
                    fld = app.uiMap.AnIn(idx);
                    fld.Value = "";
                end
                app.inSwitchCallback = true;
                app.PCCBreakerSwitch.Value = "Off";
                app.inSwitchCallback = false;
            catch
            end
        end

        % Callback function: not associated with a component
        function PCCBreakerSwitchValueChanged(app, event)
            if app.inSwitchCallback, return; end

            if ~app.isConnected
                uialert(app.UIFigure, "Not connected to IED.", "IEC61850");
                app.inSwitchCallback = true;
                app.PCCBreakerSwitch.Value = "Off";
                app.inSwitchCallback = false;
                return;
            end

            desiredOn = strcmp(app.PCCBreakerSwitch.Value, "On");
            [ok, msg] = app.operateBreaker(desiredOn);

            if ~ok
                uialert(app.UIFigure, msg, "IEC61850");
            end

            pause(0.05);
            app.pollOnce();

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ConnectToIEDsButton = uibutton(app.UIFigure, 'push');
            app.ConnectToIEDsButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectToIEDsButtonPushed, true);
            app.ConnectToIEDsButton.Text = 'Connect To IEDs';
            app.DisconnectFromIEDsButton = uibutton(app.UIFigure, 'push');
            app.DisconnectFromIEDsButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectFromIEDsButtonPushed, true);
            app.DisconnectFromIEDsButton.Text = 'Disconnect From IEDs';
            app.vUtilityGridEditFieldLabel = uilabel(app.UIFigure);
            app.vUtilityGridEditFieldLabel.Text = 'vUtilityGrid';
            app.vUtilityGridEditField = uieditfield(app.UIFigure, 'text');
            app.vMicroGridEditFieldLabel = uilabel(app.UIFigure);
            app.vMicroGridEditFieldLabel.Text = 'vMicroGrid';
            app.vMicroGridEditField = uieditfield(app.UIFigure, 'text');
            app.iUtilityGridEditFieldLabel = uilabel(app.UIFigure);
            app.iUtilityGridEditFieldLabel.Text = 'iUtilityGrid';
            app.iUtilityGridEditField = uieditfield(app.UIFigure, 'text');
            app.iMicrogridEditField_2Label = uilabel(app.UIFigure);
            app.iMicrogridEditField_2Label.Text = 'iMicrogrid';
            app.iMicrogridEditField_2 = uieditfield(app.UIFigure, 'text');
            app.phiUtilityGridEditFieldLabel = uilabel(app.UIFigure);
            app.phiUtilityGridEditFieldLabel.Text = 'phiUtilityGrid';
            app.phiUtilityGridEditField = uieditfield(app.UIFigure, 'text');
            app.phiMicroGridEditFieldLabel = uilabel(app.UIFigure);
            app.phiMicroGridEditFieldLabel.Text = 'phiMicroGrid';
            app.phiMicroGridEditField = uieditfield(app.UIFigure, 'text');
            app.PGridEditFieldLabel = uilabel(app.UIFigure);
            app.PGridEditFieldLabel.Text = 'PGrid';
            app.PGridEditField = uieditfield(app.UIFigure, 'text');
            app.QGridEditFieldLabel = uilabel(app.UIFigure);
            app.QGridEditFieldLabel.Text = 'QGrid';
            app.QGridEditField = uieditfield(app.UIFigure, 'text');
            app.PCCBreakerSwitchLabel = uilabel(app.UIFigure);
            app.PCCBreakerSwitchLabel.Text = 'PCC Breaker';
            app.PCCBreakerSwitch = uiswitch(app.UIFigure, 'rocker');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end